classdef corazon < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GABANPERLabel                  matlab.ui.control.Label
        HiperVizda                     matlab.ui.control.DropDown
        HipertrofiaVizdaDropDownLabel  matlab.ui.control.Label
        paraHTALabel                   matlab.ui.control.Label
        Diabetes                       matlab.ui.control.DropDown
        DiabetesLabel                  matlab.ui.control.Label
        FUMADropDown                   matlab.ui.control.DropDown
        FumaDropDownLabel              matlab.ui.control.Label
        probabilidad                   matlab.ui.control.EditField
        probabilidadderiesgoLabel      matlab.ui.control.Label
        CalcularButton                 matlab.ui.control.Button
        proximos10aosLabel             matlab.ui.control.Label
        enfermedadcardiovascularLabel  matlab.ui.control.Label
        defallecerdeunaLabel           matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        CLASF                          matlab.ui.control.EditField
        ResultadosLabel                matlab.ui.control.Label
        colhdl                         matlab.ui.control.NumericEditField
        HDLEditFieldLabel              matlab.ui.control.Label
        coltotal                       matlab.ui.control.NumericEditField
        TotalEditFieldLabel            matlab.ui.control.Label
        colesterol                     matlab.ui.control.Label
        tratamiento                    matlab.ui.control.DropDown
        TratamientoLabel               matlab.ui.control.Label
        dias                           matlab.ui.control.NumericEditField
        DiastolicaEditFieldLabel       matlab.ui.control.Label
        sist                           matlab.ui.control.NumericEditField
        SistolicaEditFieldLabel        matlab.ui.control.Label
        PresionArterialmmHgLabel       matlab.ui.control.Label
        genero                         matlab.ui.control.DropDown
        SexoDropDownLabel              matlab.ui.control.Label
        edad                           matlab.ui.control.NumericEditField
        EdadEditFieldLabel             matlab.ui.control.Label
        BasadoenelFraminghamHeartStudyLabel  matlab.ui.control.Label
        RiesgodeenfermedadCardioVascularLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
    end
    
    methods (Access = private)
        
        function hombres(app)
            value = app.genero.Value;
            trat = app.tratamiento.Value;
            fuma = app.FUMADropDown.Value;
            Edad = app.edad.Value;
            Sis = app.sist.Value;
            Coltot = app.coltotal.Value;
            ColHDL = app.colhdl.Value;
            Diabe = app.Diabetes.Value;
            hipervizda = app.HiperVizda.Value;
            prob = 0;

           %hombre
            if (strcmp(value, 'Masculino'))
                if (Edad >= 20 && Edad <= 34)
                    prob = prob - 9;
                elseif (Edad >= 35 && Edad <= 39)
                    prob = prob - 4; 
                elseif (Edad >= 40 && Edad <= 44)
                    prob = prob + 0;  
                elseif (Edad >= 45 && Edad <= 49)
                    prob = prob + 3; 
                elseif (Edad >= 50 && Edad <= 54)
                    prob = prob + 6;                 
                elseif (Edad >= 55 && Edad <= 59)
                    prob = prob + 8; 
                elseif (Edad >= 60 && Edad <= 64)
                    prob = prob + 10;    
                elseif (Edad >= 65 && Edad <= 69)
                    prob = prob + 11;                 
                elseif (Edad >= 70 && Edad <= 74)
                    prob = prob + 12; 
                elseif (Edad >= 75 && Edad <= 79)
                    prob = prob + 13; 
                end
            end 

            if (strcmp(fuma, 'si'))
                    prob = prob + 4;
                else
                    prob = prob+0;
            end
   
            if (Coltot < 160 && Edad >= 20 && Edad <= 39 )
                prob = prob + 0;
            elseif (Coltot >= 160 && Coltot <= 199 && Edad >= 20 && Edad <= 39)
                prob = prob + 4; 
            
            elseif (Coltot >= 200 && Coltot <= 239 && Edad >= 20 && Edad <= 39)
                prob = prob + 7; 
            
            elseif (Coltot >= 240 && Coltot <= 279 && Edad >= 20 && Edad <= 39)
                prob = prob + 9; 
            
            elseif (Coltot >= 280 && Edad >= 20 && Edad <= 39)
                prob = prob + 11;
            end
       
            if (Coltot < 160 && Edad >= 40 && Edad <= 49 )
                prob = prob + 0;
            elseif (Coltot >= 160 && Coltot <= 199 && Edad >= 40 && Edad <= 49)
                prob = prob + 3; 
            elseif (Coltot >= 200 && Coltot <= 239 && Edad >= 40 && Edad <= 49)
                prob = prob + 5; 
            elseif (Coltot >= 240 && Coltot <= 279 && Edad >= 40 && Edad <= 49)
                prob = prob + 6; 
            elseif (Coltot >= 280 && Edad >= 40 && Edad <= 49)
                prob = prob + 8;
            end
            
            if (Coltot < 160 && Edad >= 50 && Edad <= 59 )
                prob = prob + 0;
            elseif (Coltot >= 160 && Coltot <= 199 && Edad >= 50 && Edad <= 59)
                prob = prob + 2; 
            elseif (Coltot >= 200 && Coltot <= 239 && Edad >= 50 && Edad <= 59)
                prob = prob + 3; 
            elseif (Coltot >= 240 && Coltot <= 279 && Edad >= 50 && Edad <= 59)
                prob = prob + 4; 
            elseif (Coltot >= 280 && Edad >= 40 && Edad >= 50 && Edad <= 59)
                prob = prob + 5;
            end
            
            if (Coltot < 160 && Edad >= 60 && Edad <= 69 )
                prob = prob + 0;
            elseif (Coltot >= 160 && Coltot <= 199 && Edad >= 60 && Edad <= 69)
                prob = prob + 1; 
            elseif (Coltot >= 200 && Coltot <= 239 && Edad >= 60 && Edad <= 69)
                prob = prob + 1; 
            elseif (Coltot >= 240 && Coltot <= 279 && Edad >= 60 && Edad <= 69)
                prob = prob + 2; 
            elseif (Coltot >= 280 && Edad >= 40 && Edad >= 60 && Edad <= 69)
                prob = prob + 3;
            end
 
            if (Coltot < 160 && Edad >= 70 && Edad <= 79 )
                prob = prob + 0;
            elseif (Coltot >= 160 && Coltot <= 199 && Edad >= 70 && Edad <= 79)
                prob = prob + 0; 
            elseif (Coltot >= 200 && Coltot <= 239 && Edad >= 70 && Edad <= 79)
                prob = prob + 0; 
            elseif (Coltot >= 240 && Coltot <= 279 && Edad >= 70 && Edad <= 79)
                prob = prob + 1; 
            elseif (Coltot >= 280 && Edad >= 40 && Edad >= 70 && Edad <= 79)
                prob = prob + 1;
            end
            
            if (ColHDL >= 60)
                prob = prob - 1;
            elseif (ColHDL >= 50 && ColHDL <= 59)
                prob = prob + 0;
            elseif (ColHDL >= 40 && ColHDL <= 49)
                prob = prob + 1;
            elseif (ColHDL < 40)
                prob = prob + 2;
            end

   
            if (Sis >= 120 && Sis <= 129 )
                if (strcmp(trat, 'Si'))
                    prob = prob + 1;
                else
                    prob = prob + 0;
                end  
            elseif (Sis >= 130 && Sis <= 159 )
                if (strcmp(trat, 'Si'))
                    prob = prob + 2;
                else
                    prob = prob + 1;
                end
            elseif (Sis >= 160)
                if (strcmp(trat, 'Si'))
                    prob = prob + 3;
                else
                    prob = prob + 2;
                end
            end

            if (strcmp(Diabe, 'Si'))
                prob = prob+3;
            else
                prob = prob+0;
            end

            if (strcmp(hipervizda, 'Si'))
                prob = prob+9;
            else
                prob = prob+0;
            end


            if (prob >= 0 && prob <5) 
                app.probabilidad.Value = '2%';
            elseif (prob >= 5 && prob <=6) 
                app.probabilidad.Value = '3%';
            elseif (prob >= 7 && prob <=8)
                app.probabilidad.Value = '4%';                
            elseif (prob == 9) 
                app.probabilidad.Value = '5%';
            elseif (prob >= 10 && prob <=11)
                app.probabilidad.Value = '6%';
            elseif (prob == 12) 
                app.probabilidad.Value = '7%';
            elseif (prob == 13) 
                app.probabilidad.Value = '8%';
            elseif (prob == 14) 
                app.probabilidad.Value = '9%';
            elseif (prob == 15) 
                app.probabilidad.Value = '10%';
            elseif (prob == 16) 
                app.probabilidad.Value = '12%';
            elseif (prob == 17) 
                app.probabilidad.Value = '13%';
            elseif (prob == 18) 
                app.probabilidad.Value = '14%';
            elseif (prob == 19) 
                app.probabilidad.Value = '16%';
            elseif (prob == 20)
                app.probabilidad.Value = '18%';                
            elseif (prob == 21) 
                app.probabilidad.Value = '19%';
            elseif (prob == 22)
                app.probabilidad.Value = '21%';
            elseif (prob == 23) 
                app.probabilidad.Value = '23%';
            elseif (prob == 24) 
                app.probabilidad.Value = '25%';
            elseif (prob == 25) 
                app.probabilidad.Value = '27%';
            elseif (prob == 26) 
                app.probabilidad.Value = '29%';
            elseif (prob == 27) 
                app.probabilidad.Value = '31%';
            elseif (prob == 28) 
                app.probabilidad.Value = '33%';
            elseif (prob == 29) 
                app.probabilidad.Value = '36%';
            elseif (prob == 30) 
                app.probabilidad.Value = '38%';
            elseif (prob == 31) 
                app.probabilidad.Value = '40%';
            elseif (prob == 32) 
                app.probabilidad.Value = '42%';
            
            else
                app.probabilidad.Value = '0%';
            end


            if (prob < 9)
            app.CLASF.Value = "Bajo";
            app.CLASF.BackgroundColor = '#D1FFC7';
            elseif (prob >= 9 && prob <= 15)
            app.CLASF.Value = "Ligero";
            app.CLASF.BackgroundColor = '#FCFEAA';
            elseif (prob >= 16 && prob <= 21)
            app.CLASF.Value = "Moderado";
            app.CLASF.BackgroundColor = '#FFC59A';
            elseif (prob >= 22 && prob <= 31)
            app.CLASF.Value = "Alto";
            app.CLASF.BackgroundColor = '#FC7373';
            elseif (prob > 31)
            app.CLASF.Value = "Muy alto";
            app.CLASF.BackgroundColor = '#C51E1E';
            end
            
        end


        function  mujeres(app)
            value = app.genero.Value;
            trat = app.tratamiento.Value;
            fuma = app.FUMADropDown.Value;
            Edad = app.edad.Value;
            Sis = app.sist.Value;
            Coltot = app.coltotal.Value;
            ColHDL = app.colhdl.Value;
            Diabe = app.Diabetes.Value;
            hipervizda = app.HiperVizda.Value;
            % Fumador = app.fumador.Value;
            prob = 0;
           if (strcmp(value, 'Femenino'))
                if (Edad >= 20 && Edad <= 34)
                    prob = prob - 7;
                elseif (Edad >= 35 && Edad <= 39)
                    prob = prob - 3; 
                elseif (Edad >= 40 && Edad <= 44)
                    prob = prob + 0;  
                elseif (Edad >= 45 && Edad <= 49)
                    prob = prob + 3; 
                elseif (Edad >= 50 && Edad <= 54)
                    prob = prob + 6;                 
                elseif (Edad >= 55 && Edad <= 59)
                    prob = prob + 8; 
                elseif (Edad >= 60 && Edad <= 64)
                    prob = prob + 10;    
                elseif (Edad >= 65 && Edad <= 69)
                    prob = prob + 12;                 
                elseif (Edad >= 70 && Edad <= 74)
                    prob = prob + 14; 
                elseif (Edad >= 75 && Edad <= 79)
                    prob = prob + 16; 
                end

           end
     
           
                if (strcmp(fuma, 'si'))
                    prob = prob + 4;
                else
                    prob = prob+0;
                end
   

            if (Coltot < 160 && Edad >= 20 && Edad <= 39 )
                prob = prob + 0;
            elseif (Coltot >= 160 && Coltot <= 199 && Edad >= 20 && Edad <= 39)
                prob = prob + 4;
            elseif (Coltot >= 200 && Coltot <= 239 && Edad >= 20 && Edad <= 39)
                prob = prob + 8;          
            elseif (Coltot >= 240 && Coltot <= 279 && Edad >= 20 && Edad <= 39)
                prob = prob + 11; 
            elseif (Coltot >= 280 && Edad >= 20 && Edad <= 39)
                prob = prob + 13;
            end
       
            if (Coltot < 160 && Edad >= 40 && Edad <= 49 )
                prob = prob + 0;
            elseif (Coltot >= 160 && Coltot <= 199 && Edad >= 40 && Edad <= 49)
                prob = prob + 3; 
            elseif (Coltot >= 200 && Coltot <= 239 && Edad >= 40 && Edad <= 49)
                prob = prob + 6; 
            elseif (Coltot >= 240 && Coltot <= 279 && Edad >= 40 && Edad <= 49)
                prob = prob + 8; 
            elseif (Coltot >= 280 && Edad >= 40 && Edad <= 49)
                prob = prob + 10;
            end
            
            if (Coltot < 160 && Edad >= 50 && Edad <= 59 )
                prob = prob + 0;
            elseif (Coltot >= 160 && Coltot <= 199 && Edad >= 50 && Edad <= 59)
                prob = prob + 2; 
            elseif (Coltot >= 200 && Coltot <= 239 && Edad >= 50 && Edad <= 59)
                prob = prob + 4; 
            elseif (Coltot >= 240 && Coltot <= 279 && Edad >= 50 && Edad <= 59)
                prob = prob + 5; 
            elseif (Coltot >= 280 && Edad >= 40 && Edad >= 50 && Edad <= 59)
                prob = prob + 7;
            end
            
            if (Coltot < 160 && Edad >= 60 && Edad <= 69 )
                prob = prob + 0;
            elseif (Coltot >= 160 && Coltot <= 199 && Edad >= 60 && Edad <= 69)
                prob = prob + 1; 
            elseif (Coltot >= 200 && Coltot <= 239 && Edad >= 60 && Edad <= 69)
                prob = prob + 2; 
            elseif (Coltot >= 240 && Coltot <= 279 && Edad >= 60 && Edad <= 69)
                prob = prob + 3; 
            elseif (Coltot >= 280 && Edad >= 40 && Edad >= 60 && Edad <= 69)
                prob = prob + 4;
            end
 
            if (Coltot < 160 && Edad >= 70 && Edad <= 79 )
                prob = prob + 0;
            elseif (Coltot >= 160 && Coltot <= 199 && Edad >= 70 && Edad <= 79)
                prob = prob + 1; 
            elseif (Coltot >= 200 && Coltot <= 239 && Edad >= 70 && Edad <= 79)
                prob = prob + 1; 
            elseif (Coltot >= 240 && Coltot <= 279 && Edad >= 70 && Edad <= 79)
                prob = prob + 2; 
            elseif (Coltot >= 280 && Edad >= 40 && Edad >= 70 && Edad <= 79)
                prob = prob + 2;
            end
            
            if (ColHDL >= 60)
                prob = prob - 1;
            elseif (ColHDL >= 50 && ColHDL <= 59)
                prob = prob + 0;
            elseif (ColHDL >= 40 && ColHDL <= 49)
                prob = prob + 1;
            elseif (ColHDL < 40)
                prob = prob + 2;
            end


            if (Sis > 120 && Sis <= 129)
                if (strcmp(trat, 'Si'))
                    prob = prob + 3;
                else
                    prob = prob + 1;
                end
            elseif (Sis >= 130 && Sis <= 139)
                if (strcmp(trat, 'Si'))
                    prob = prob + 4;
                else
                    prob = prob + 2;
                end
            elseif (Sis >= 140 && Sis <= 159)
                if (strcmp(trat, 'Si'))
                    prob = prob + 5;
                else
                    prob = prob + 3;
                end
            elseif (Sis >= 160)
                if (strcmp(trat, 'Si'))
                    prob = prob + 6;
                else
                    prob = prob + 4;
                end
            end
            
            if (strcmp(Diabe, 'Si'))
                prob = prob+6;
            else
                prob = prob+0;
            end
            
            if (strcmp(hipervizda, 'Si'))
                prob = prob+9;
            else
                prob = prob+0;
            end
             

            if (prob >= 0 && prob <5) 
                app.probabilidad.Value = '2%';
            elseif (prob >= 5 && prob <=6) 
                app.probabilidad.Value = '3%';
            elseif (prob >= 7 && prob <=8)
                app.probabilidad.Value = '4%';                
            elseif (prob == 9) 
                app.probabilidad.Value = '5%';
            elseif (prob >= 10 && prob <=11)
                app.probabilidad.Value = '6%';
            elseif (prob == 12) 
                app.probabilidad.Value = '7%';
            elseif (prob == 13) 
                app.probabilidad.Value = '8%';
            elseif (prob == 14) 
                app.probabilidad.Value = '9%';
            elseif (prob == 15) 
                app.probabilidad.Value = '10%';
            elseif (prob == 16) 
                app.probabilidad.Value = '12%';
            elseif (prob == 17) 
                app.probabilidad.Value = '13%';
            elseif (prob == 18) 
                app.probabilidad.Value = '14%';
            elseif (prob == 19) 
                app.probabilidad.Value = '16%';
            elseif (prob == 20)
                app.probabilidad.Value = '18%';                
            elseif (prob == 21) 
                app.probabilidad.Value = '19%';
            elseif (prob == 22)
                app.probabilidad.Value = '21%';
            elseif (prob == 23) 
                app.probabilidad.Value = '23%';
            elseif (prob == 24) 
                app.probabilidad.Value = '25%';
            elseif (prob == 25) 
                app.probabilidad.Value = '27%';
            elseif (prob == 26) 
                app.probabilidad.Value = '29%';
            elseif (prob == 27) 
                app.probabilidad.Value = '31%';
            elseif (prob == 28) 
                app.probabilidad.Value = '33%';
            elseif (prob == 29) 
                app.probabilidad.Value = '36%';
            elseif (prob == 30) 
                app.probabilidad.Value = '38%';
            elseif (prob == 31) 
                app.probabilidad.Value = '40%';
            elseif (prob == 32) 
                app.probabilidad.Value = '42%';
            
            else
                app.probabilidad.Value = '0%';
            end

            if (prob < 9)
            app.CLASF.Value = "Bajo";
            app.CLASF.BackgroundColor = '#D1FFC7';
            elseif (prob >= 9 && prob <= 15)
            app.CLASF.Value = "Ligero";
            app.CLASF.BackgroundColor = '#FCFEAA';
            elseif (prob >= 16 && prob <= 21)
            app.CLASF.Value = "Moderado";
            app.CLASF.BackgroundColor = '#FFC59A';
            elseif (prob >= 22 && prob <= 31)
            app.CLASF.Value = "Alto";
            app.CLASF.BackgroundColor = '#FC7373';
            elseif (prob > 31)
            app.CLASF.Value = "Muy alto";
            app.CLASF.BackgroundColor = '#C51E1E';
            end
              
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.hombres();
            app.mujeres();  
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            
       
        if strcmp(app.genero.Value, 'Masculino')
        hombres(app);
            elseif strcmp(app.genero.Value, 'Femenino')
        mujeres(app);
        end


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RiesgodeenfermedadCardioVascularLabel = uilabel(app.UIFigure);
            app.RiesgodeenfermedadCardioVascularLabel.Text = 'Riesgo de enfermedad CardioVascular';
            app.BasadoenelFraminghamHeartStudyLabel = uilabel(app.UIFigure);
            app.BasadoenelFraminghamHeartStudyLabel.Text = 'Basado en el Framingham Heart Study';
            app.EdadEditFieldLabel = uilabel(app.UIFigure);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.edad = uieditfield(app.UIFigure, 'numeric');
            app.edad.Limits = [20 79];
            app.edad.Value = 20;
            app.SexoDropDownLabel = uilabel(app.UIFigure);
            app.SexoDropDownLabel.Text = 'Sexo';
            app.genero = uidropdown(app.UIFigure);
            app.genero.Items = {'Femenino', 'Masculino'};
            app.genero.Value = 'Femenino';
            app.PresionArterialmmHgLabel = uilabel(app.UIFigure);
            app.PresionArterialmmHgLabel.Text = 'Presion Arterial (mmHg)';
            app.SistolicaEditFieldLabel = uilabel(app.UIFigure);
            app.SistolicaEditFieldLabel.Text = 'Sistolica';
            app.sist = uieditfield(app.UIFigure, 'numeric');
            app.sist.Value = 120;
            app.DiastolicaEditFieldLabel = uilabel(app.UIFigure);
            app.DiastolicaEditFieldLabel.Text = 'Diastolica';
            app.dias = uieditfield(app.UIFigure, 'numeric');
            app.dias.Value = 60;
            app.TratamientoLabel = uilabel(app.UIFigure);
            app.TratamientoLabel.Text = 'Tratamiento ';
            app.tratamiento = uidropdown(app.UIFigure);
            app.tratamiento.Items = {'Si', 'No'};
            app.tratamiento.Value = 'Si';
            app.colesterol = uilabel(app.UIFigure);
            app.colesterol.Text = 'Colesterol (mg/dL)';
            app.TotalEditFieldLabel = uilabel(app.UIFigure);
            app.TotalEditFieldLabel.Text = 'Total';
            app.coltotal = uieditfield(app.UIFigure, 'numeric');
            app.coltotal.Limits = [160 Inf];
            app.coltotal.Value = 160;
            app.HDLEditFieldLabel = uilabel(app.UIFigure);
            app.HDLEditFieldLabel.Text = 'HDL';
            app.colhdl = uieditfield(app.UIFigure, 'numeric');
            app.colhdl.Limits = [40 Inf];
            app.colhdl.Value = 40;
            app.ResultadosLabel = uilabel(app.UIFigure);
            app.ResultadosLabel.Text = 'Resultados';
            app.CLASF = uieditfield(app.UIFigure, 'text');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'image-removebg-preview.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'image-removebg-preview (1).png');
            app.defallecerdeunaLabel = uilabel(app.UIFigure);
            app.defallecerdeunaLabel.Text = 'de fallecer de una';
            app.enfermedadcardiovascularLabel = uilabel(app.UIFigure);
            app.enfermedadcardiovascularLabel.Text = 'enfermedad cardiovascular';
            app.proximos10aosLabel = uilabel(app.UIFigure);
            app.proximos10aosLabel.Text = '(proximos 10 años)';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.probabilidadderiesgoLabel = uilabel(app.UIFigure);
            app.probabilidadderiesgoLabel.Text = 'Probabilidad de riesgo';
            app.probabilidad = uieditfield(app.UIFigure, 'text');
            app.FumaDropDownLabel = uilabel(app.UIFigure);
            app.FumaDropDownLabel.Text = 'Fuma';
            app.FUMADropDown = uidropdown(app.UIFigure);
            app.FUMADropDown.Items = {'si', 'no'};
            app.FUMADropDown.Value = 'si';
            app.DiabetesLabel = uilabel(app.UIFigure);
            app.DiabetesLabel.Text = 'Diabetes';
            app.Diabetes = uidropdown(app.UIFigure);
            app.Diabetes.Items = {'si', 'no'};
            app.Diabetes.Value = 'si';
            app.paraHTALabel = uilabel(app.UIFigure);
            app.paraHTALabel.Text = 'para HTA';
            app.HipertrofiaVizdaDropDownLabel = uilabel(app.UIFigure);
            app.HipertrofiaVizdaDropDownLabel.Text = 'Hipertrofia Vizda.';
            app.HiperVizda = uidropdown(app.UIFigure);
            app.HiperVizda.Items = {'Si', 'No'};
            app.HiperVizda.Value = 'Si';
            app.GABANPERLabel = uilabel(app.UIFigure);
            app.GABANPERLabel.Text = 'GABANPER';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = corazon

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end